classdef InstrumentationAppReflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        Credits                   matlab.ui.control.Label
        PlotFreq                  matlab.ui.control.UIAxes
        PlotTime                  matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        Reset                     matlab.ui.control.Button
        Restart                   matlab.ui.control.Button
        PanelDoor                 matlab.ui.container.Panel
        TauDoor                   matlab.ui.control.NumericEditField
        tausLabel                 matlab.ui.control.Label
        PanelPlotting             matlab.ui.container.Panel
        ButtonGroup               matlab.ui.container.ButtonGroup
        SubtractSignalButton      matlab.ui.control.RadioButton
        AddSignalButton           matlab.ui.control.RadioButton
        SimplePlotButton          matlab.ui.control.RadioButton
        PlotButton                matlab.ui.control.Button
        PanelFreq                 matlab.ui.container.Panel
        UpdateFreqAnalysisButton  matlab.ui.control.Button
        Scale                     matlab.ui.control.DropDown
        ScaleLabel                matlab.ui.control.Label
        Output                    matlab.ui.control.DropDown
        OutputLabel               matlab.ui.control.Label
        PanelTime                 matlab.ui.container.Panel
        Frequency                 matlab.ui.control.NumericEditField
        FrequencyHzLabel          matlab.ui.control.Label
        Amplitude                 matlab.ui.control.NumericEditField
        AmplitudemLabel           matlab.ui.control.Label
        Period                    matlab.ui.control.NumericEditField
        TotalPeriodsLabel         matlab.ui.control.Label
        Fs                        matlab.ui.control.NumericEditField
        SampleFreqHzLabel         matlab.ui.control.Label
        SignalType                matlab.ui.control.DropDown
        SignalTypeLabel           matlab.ui.control.Label
        Menu                      matlab.ui.container.Menu
        OpenFileMenu              matlab.ui.container.Menu
        SaveDataMenu              matlab.ui.container.Menu
        SignalGeneratorMenu       matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        counter         % Signal Counter
        matrix_signals  % Signals Matrix
    end

    methods (Access = private)
        function [signal,t] = SimpleTimePlotting(app)
            % TIME PLOT
            value = app.SignalType.Value;
            switch true
                case (strcmpi(value,'Sine')==1)             % SINE
                    A = app.Amplitude.Value;                % Amplitude
                    sampleFreq = app.Fs.Value;              % Sample Frequency
                    freq = app.Frequency.Value;	            % Frequency
                    omega = 2*pi*freq;                      % Omega
                    T = app.Period.Value;                   % Total Period
                    t = 0:(1/sampleFreq):(T);               % Time Vector
                    signal = A*sin(omega*t);            % y(t) - Sine
                    plot(app.PlotTime,t,signal,'r','linewidth',3)

                case (strcmpi(value, 'Door')==1)                % DOOR
                    tau = app.TauDoor.Value;                    % Tau
                    A = app.Amplitude.Value;                    % Amplitude
                    sampleFreq = app.Fs.Value;                  % Sample Frequency
                    T = app.Period.Value;                       % Total Period
                    t = 0:(1/sampleFreq):(T);                   % Time Vector
                    signal = A*(heaviside(t) - heaviside(t-tau));% y(t) - Door
                    plot(app.PlotTime,t,signal,'r','linewidth',3)

                case (strcmpi(value, 'Random')==1)          % RANDOM
                    A = app.Amplitude.Value;                % Amplitude
                    sampleFreq = app.Fs.Value;              % Sample Frequency
                    T = app.Period.Value;                   % Total Period
                    t = 0:(1/sampleFreq):(T);               % Time Vector
                    signal = A*randn(length(t),1);          % y(t) - Random
                    plot(app.PlotTime,t,signal,'r','linewidth',3)
            end
        end
        
        function AdvancedTimePlotting(app,signal,time)
            plot(app.PlotTime,time,signal,'r','linewidth',3)
        end
        
        function freqPlotting(app,signal)
            % FREQUENCY PLOT
            value1 = app.Output.Value;      % Output Type Frequency
            switch true
                case (strcmpi(value1,'FFT')==1)             % FFT
                    sampleFreq = app.Fs.Value;              % Sample Frequency
                    N = length(signal);                     % Length Signal
                    FFTSignal = fft(signal,N)/N;
                    FFTAmplitude = 2*abs(FFTSignal(1:N/2+1));
                    FFTFrequency = sampleFreq/2*linspace(0,1,N/2+1);
                    plot(app.PlotFreq,FFTFrequency,FFTAmplitude,'b','linewidth',3)
                    xlabel(app.PlotFreq, 'Frequency [Hz]')
                    ylabel(app.PlotFreq, 'FFT Y')
                    app.PlotFreq.XLim = [0 sampleFreq/2];

                case (strcmpi(value1,'PSD')==1)         	% PSD
                    [Y,PSDfreq] = pwelch(signal);
                    plot(app.PlotFreq,PSDfreq,Y,'b','linewidth',3)
                    xlabel(app.PlotFreq, 'Frequency [Hz]')
                    ylabel(app.PlotFreq, 'PSD Y')
                    app.PlotFreq.XLim = [0 max(PSDfreq)];

                case (strcmpi(value1,'Autocorrelation')==1) % Autocorrelation
                    [Rxx,lags] = xcorr(signal,signal);
                    plot(app.PlotFreq,lags,Rxx,'b','linewidth',3)
                    xlabel(app.PlotFreq, 'Time [s]')
                    ylabel(app.PlotFreq, 'ACR')
                    app.PlotFreq.XLim = [min(lags) max(lags)];
            end
            value2 = app.Scale.Value;       % Data Scale
            switch true
                case (strcmpi(value2,'Linear')==1)
                    app.PlotFreq.XScale = 'linear';
                    app.PlotFreq.YScale = 'linear';
                case (strcmpi(value2,'LogY')==1)
                    app.PlotFreq.XScale = 'linear';
                    app.PlotFreq.YScale = 'log';
                case (strcmpi(value2,'LogX')==1)
                    app.PlotFreq.XScale = 'log';
                    app.PlotFreq.YScale = 'linear';
                case (strcmpi(value2,'LogLog')==1)
                    app.PlotFreq.XScale = 'log';
                    app.PlotFreq.YScale = 'log';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {1015, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: SignalGeneratorMenu
        function SignalGeneratorMenuSelected(app, event)
            app.PanelTime.Visible = 'on';   % Initializing
            app.PanelPlotting.Visible = 'on';
            app.SubtractSignalButton.Enable = 'off';
            app.AddSignalButton.Enable = 'off';
            app.PanelFreq.Visible = 'on';
            app.PlotButton.Visible = 'on';
            app.PanelDoor.Visible = 'off';
            app.UpdateFreqAnalysisButton.Enable = 'off';
            app.Restart.Visible = 'on';
            app.counter = 1;                % Signal Counter
        end

        % Value changed function: SignalType
        function SignalTypeValueChanged(app, event)
            value = app.SignalType.Value;   % Toogle Visibility
            switch true                     % based on Selection
                case (strcmpi(value,'Sine')==1)
                    app.PanelTime.Visible = 'on';
                    app.Frequency.Enable = 'on';
                    app.PanelFreq.Visible = 'on';
                    app.PlotButton.Visible = 'on';
                    app.PanelDoor.Visible = 'off';
                case (strcmpi(value, 'Door')==1)
                    app.PanelTime.Visible = 'on';
                    app.Frequency.Enable = 'off';
                    app.PanelFreq.Visible = 'on';
                    app.PlotButton.Visible = 'on';
                    app.PanelDoor.Visible = 'on';
                case (strcmpi(value, 'Random')==1)
                    app.PanelTime.Visible = 'on';
                    app.Frequency.Enable = 'off';
                    app.PanelFreq.Visible = 'on';
                    app.PlotButton.Visible = 'on';
                    app.PanelDoor.Visible = 'off';
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonValueChanged(app, event)
            switch app.ButtonGroup.SelectedObject.Text
                case 'Simple Plot'
                    app.matrix_signals = [];
                    [signal,~] = SimpleTimePlotting(app);
                    freqPlotting(app,signal)
                    app.matrix_signals(app.counter,:) = signal;
                    app.SubtractSignalButton.Enable = 'on';
                    app.AddSignalButton.Enable = 'on';
                    app.Reset.Visible = 'on';
                    app.Fs.Enable = 'off';
                    app.Period.Enable = 'off';
                    app.UpdateFreqAnalysisButton.Enable = 'on';
                    save SavedSignal signal

                case 'Add Signal'
                    app.counter = app.counter + 1;
                    [signal,time] = SimpleTimePlotting(app);
                    app.matrix_signals(app.counter,:) = signal;
                    signal = sum(app.matrix_signals);
                    AdvancedTimePlotting(app,signal,time)
                    freqPlotting(app,signal)
                    save SavedSignal signal

                case 'Subtract Signal'
                    app.counter = app.counter + 1;
                    [signal,time] = SimpleTimePlotting(app);
                    app.matrix_signals(app.counter,:) = -signal;
                    signal = sum(app.matrix_signals);
                    AdvancedTimePlotting(app,signal,time)
                    freqPlotting(app,signal)
                    save SavedSignal signal
            end

        end

        % Button pushed function: Restart
        function RestartButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            InstrumentationApp();
            close(app.UIFigure)
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.SimplePlotButton.Value = 1;
            app.SubtractSignalButton.Enable = 'off';
            app.AddSignalButton.Enable = 'off';
            app.Fs.Enable = 'on';
            app.Period.Enable = 'on';
            app.Reset.Visible = 'off';
        end

        % Button pushed function: UpdateFreqAnalysisButton
        function UpdateFreqAnalysisButtonPushed(app, event)
            load SavedSignal %#ok<LOAD> 
            freqPlotting(app,signal)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotTime = uiaxes(app.LeftPanel);
            xlabel(app.PlotTime, 'Time [s]')
            ylabel(app.PlotTime, 'y(t) [m]')
            app.PlotFreq = uiaxes(app.LeftPanel);
            xlabel(app.PlotFreq, 'Frequency [Hz]')
            ylabel(app.PlotFreq, 'Amplitude')
            app.Credits = uilabel(app.LeftPanel);
            app.Credits.Text = 'Application by Estevão Fuzaro (2021)';
            app.RightPanel = uipanel(app.GridLayout);
            app.PanelTime = uipanel(app.RightPanel);
            app.PanelTime.Title = 'Signal Parameters';
            app.PanelTime.Visible = 'off';
            app.SignalTypeLabel = uilabel(app.PanelTime);
            app.SignalTypeLabel.Text = 'Signal Type';
            app.SignalType = uidropdown(app.PanelTime);
            app.SignalType.Items = {'Sine', 'Door', 'Random'};
            app.SignalType.ValueChangedFcn = createCallbackFcn(app, @SignalTypeValueChanged, true);
            app.SignalType.Value = 'Sine';
            app.SampleFreqHzLabel = uilabel(app.PanelTime);
            app.SampleFreqHzLabel.Text = 'Sample Freq [Hz]';
            app.Fs = uieditfield(app.PanelTime, 'numeric');
            app.Fs.Value = 2000;
            app.TotalPeriodsLabel = uilabel(app.PanelTime);
            app.TotalPeriodsLabel.Text = 'Total Period [s]';
            app.Period = uieditfield(app.PanelTime, 'numeric');
            app.Period.Value = 1;
            app.AmplitudemLabel = uilabel(app.PanelTime);
            app.AmplitudemLabel.Text = 'Amplitude [m]';
            app.Amplitude = uieditfield(app.PanelTime, 'numeric');
            app.Amplitude.Value = 1;
            app.FrequencyHzLabel = uilabel(app.PanelTime);
            app.FrequencyHzLabel.Text = 'Frequency [Hz]';
            app.Frequency = uieditfield(app.PanelTime, 'numeric');
            app.Frequency.Value = 20;
            app.PanelFreq = uipanel(app.RightPanel);
            app.PanelFreq.Title = 'Frequency Analysis';
            app.PanelFreq.Visible = 'off';
            app.OutputLabel = uilabel(app.PanelFreq);
            app.OutputLabel.Text = 'Output';
            app.Output = uidropdown(app.PanelFreq);
            app.Output.Items = {'FFT', 'PSD', 'Autocorrelation'};
            app.Output.Value = 'FFT';
            app.ScaleLabel = uilabel(app.PanelFreq);
            app.ScaleLabel.Text = 'Scale';
            app.Scale = uidropdown(app.PanelFreq);
            app.Scale.Items = {'Linear', 'LogX', 'LogY', 'LogLog'};
            app.Scale.Value = 'LogX';
            app.UpdateFreqAnalysisButton = uibutton(app.PanelFreq, 'push');
            app.UpdateFreqAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateFreqAnalysisButtonPushed, true);
            app.UpdateFreqAnalysisButton.Text = 'Update Freq. Analysis';
            app.PanelPlotting = uipanel(app.RightPanel);
            app.PanelPlotting.Title = 'Plotting Options';
            app.PanelPlotting.Visible = 'off';
            app.PlotButton = uibutton(app.PanelPlotting, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonValueChanged, true);
            app.PlotButton.Text = 'Plot';
            app.ButtonGroup = uibuttongroup(app.PanelPlotting);
            app.SimplePlotButton = uiradiobutton(app.ButtonGroup);
            app.SimplePlotButton.Text = 'Simple Plot';
            app.SimplePlotButton.Value = true;
            app.AddSignalButton = uiradiobutton(app.ButtonGroup);
            app.AddSignalButton.Text = 'Add Signal';
            app.SubtractSignalButton = uiradiobutton(app.ButtonGroup);
            app.SubtractSignalButton.Text = 'Subtract Signal';
            app.PanelDoor = uipanel(app.RightPanel);
            app.PanelDoor.Visible = 'off';
            app.tausLabel = uilabel(app.PanelDoor);
            app.tausLabel.Interpreter = 'latex';
            app.tausLabel.Text = '$\tau$ [s]';
            app.TauDoor = uieditfield(app.PanelDoor, 'numeric');
            app.TauDoor.Value = 0.5;
            app.Restart = uibutton(app.RightPanel, 'push');
            app.Restart.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.Restart.Visible = 'off';
            app.Restart.Text = 'Restart App';
            app.Reset = uibutton(app.RightPanel, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Visible = 'off';
            app.Reset.Text = 'Reset';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.OpenFileMenu = uimenu(app.Menu);
            app.OpenFileMenu.Text = 'Open File';
            app.SaveDataMenu = uimenu(app.Menu);
            app.SaveDataMenu.Text = 'Save Data';
            app.SignalGeneratorMenu = uimenu(app.Menu);
            app.SignalGeneratorMenu.MenuSelectedFcn = createCallbackFcn(app, @SignalGeneratorMenuSelected, true);
            app.SignalGeneratorMenu.Separator = 'on';
            app.SignalGeneratorMenu.Text = 'Signal Generator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InstrumentationAppReflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end